classdef newcode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WeightTable              matlab.ui.control.Table
        ResultsLogTextArea       matlab.ui.control.TextArea
        ResultsLogTextAreaLabel  matlab.ui.control.Label
        HistogramButton          matlab.ui.control.Button
        DiseaseDetectionButton   matlab.ui.control.Button
        GrayscaleButton          matlab.ui.control.Button
        RMSContrastButton        matlab.ui.control.Button
        UploadImage              matlab.ui.control.Button
        UIAxes1                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage
        GrayImage
        ImageCount = 0
    end
    
    properties (Access = public)
        Property4 % Description
    end
    
    
    methods (Access = public)
        
        function [counts, levels] = calculateHistogram(~, img)
            counts = zeros(1, 256);
            levels = 0:255;
            [rows, cols] = size(img);
                for r = 1:rows
                    for c = 1:cols
                        val = img(r,c) + 1; % Shift 0-255 to 1-256 index
                        counts(val) = counts(val) + 1;
                    end
                end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImage
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp'}, 'Select Leaf Image');
            if isequal(file,0), return; end
            
            % Reset Logic for new image
            app.ImageCount = app.ImageCount + 1;
            fullPath = fullfile(path, file);
            
            try
                app.OriginalImage = imread(fullPath);
                imshow(app.OriginalImage, 'Parent', app.UIAxes1);
                title(app.UIAxes1, 'Original Image');
                
                % Clear other axes to avoid confusion
                cla(app.UIAxes2); cla(app.UIAxes3); cla(app.UIAxes4);
                app.GrayImage = []; % Reset gray
                app.WeightTable.Data = [];
                
                app.ResultsLogTextArea.Value = {
                    sprintf(' Image %d Uploaded Successfully', app.ImageCount);
                    sprintf('File: %s', file)
                };
            catch ME
                uialert(app.UIFigure, ME.message, 'File Error');
            end

        end

        % Button pushed function: RMSContrastButton
        function RMSContrastButtonPushed(app, event)
            if isempty(app.GrayImage)
                uialert(app.UIFigure,'Create grayscale image first','Error'); return;
            end
            
            C = app.calculateRMS(app.GrayImage);
            app.ResultsLogTextArea.Value = {sprintf('Current RMS Contrast: %.2f', C)};

   
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)

            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Upload an image first','Error'); return;
            end
            
            app.ResultsLogTextArea.Value = {'⚙️ Optimizing Grayscale... Please wait.'};
            drawnow; 
            
            % Search space 
            weights = 0:0.1:1;
            
            % Variables to store results for the table
            resultsMatrix = []; 
            bestC = 0;
            bestGray = [];
            bestRGB = [0 0 0];
            
            imgDouble = double(app.OriginalImage); 
            
            for r = weights
                for g = 0:0.1:(1-r)
                    b = 1 - r - g;
                    
                    % Vectorized Weighted Conversion
                    grayTemp = uint8(r*imgDouble(:,:,1) + g*imgDouble(:,:,2) + b*imgDouble(:,:,3));
                    
                    % Calculate Contrast
                    c = app.calculateRMS(grayTemp);
                    
                    % Store data for the table: [R, G, B, Contrast]
                    resultsMatrix = [resultsMatrix; r, g, b, c];
                    
                    % Track Best
                    if c > bestC
                        bestC = c;
                        bestGray = grayTemp;
                        bestRGB = [r g b];
                    end
                end
            end
            
          %creat the cmd val
            % Sort the matrix so the BEST contrast is at the top
            resultsMatrix = sortrows(resultsMatrix, 4, 'descend');
            % Display RGB weight combinations in GUI table (Top 20)
            rowsToShow = min(20, size(resultsMatrix,1));
            app.WeightTable.Data = resultsMatrix(1:rowsToShow, :);
            app.WeightTable.ColumnName = {'R','G','B','RMS Contrast'};

            % Print Header to Command Window
            fprintf('\n\n--- OPTIMIZATION RESULTS (Top 20) ---\n');
            fprintf('%5s %5s %5s %10s\n', 'R', 'G', 'B', 'Contrast');
            fprintf('%5s %5s %5s %10s\n', '---', '---', '---', '--------');
            
            % Print all rows (or just top 20 to keep it clean)
            rowsToShow = size(resultsMatrix, 1); 
            for i = 1:rowsToShow
                fprintf('%5.1f %5.1f %5.1f %10.3f\n', ...
                    resultsMatrix(i,1), resultsMatrix(i,2), resultsMatrix(i,3), resultsMatrix(i,4));
            end
            fprintf('-------------------------------------\n\n');
            
            app.GrayImage = bestGray;
            imshow(app.GrayImage, 'Parent', app.UIAxes2);
            title(app.UIAxes2, sprintf('Best Contrast (RMS: %.1f)', bestC));
            
            app.ResultsLogTextArea.Value = { 
                sprintf('Grayscale Optimized');
                sprintf('Best Weights: R=%.1f  G=%.1f  B=%.1f', bestRGB);
                sprintf('Max RMS Contrast: %.2f', bestC);
                'Check Command Window for full table.'
            };

        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)

            if isempty(app.GrayImage)
                uialert(app.UIFigure,'Grayscale image not found','Error'); return;
            end
            
            % Use Custom Histogram Function
            [counts, levels] = app.calculateHistogram(app.GrayImage);
            
            bar(app.UIAxes4, levels, counts, 'BarWidth', 1, 'FaceColor', [0.2 0.6 0.8]);
            title(app.UIAxes4, 'Grayscale Distribution');
            xlabel(app.UIAxes4,'Intensity'); ylabel(app.UIAxes4,'Pixels');
            xlim(app.UIAxes4, [0 255]);
            
            app.ResultsLogTextArea.Value = {'Custom Histogram Generated'};

            
        end

        % Button pushed function: DiseaseDetectionButton
        function DiseaseDetectionButtonPushed(app, event)

            if isempty(app.GrayImage)
                uialert(app.UIFigure,'Convert to grayscale first','Error'); return;
            end
            
            % A. Noise Removal (Pro Thinking: Median Filter)
            filtered = medfilt2(app.GrayImage, [3 3]);
            
            % B. Thresholding (Fixed 120 works well for these leaves)
            T = 120; 
            bw = filtered < T; 
            
            % C. Morphological Closing with Custom Kernel
            SE = [0 0 1 0 0; 0 0 1 0 0; 1 1 1 1 1; 0 0 1 0 0; 0 0 1 0 0];
            
            % Dilate then Erode (Closing)
            cleaned = imerode(imdilate(bw, SE), SE);
            
            % D. Display Results
            imshow(cleaned, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Disease Mask (Segmented)');
            
            % E. Severity Calculation
            totalPixels = numel(cleaned);
            diseasePixels = sum(cleaned(:));
            severity = (diseasePixels / totalPixels) * 100;
            
            % F. Status Logic
            status = "Healthy";
            if severity > 1 && severity < 10
                status = "Mild Infection";
            elseif severity >= 10
                status = "Severe Infection";
            end
            
            app.ResultsLogTextArea.Value = {
                'Disease Analysis Complete';
                sprintf('Infection Area: %.2f%%', severity);
                sprintf('Diagnosis: %s', status)
            };
        end
        % Helper: RMS Contrast Calculation
        function rmsVal = calculateRMS(~, img)
            imgD = double(img);
            avg = mean(imgD(:));
            rmsVal = sqrt(mean((imgD(:) - avg).^2));

  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grayscale')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Binary Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Original Image')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UploadImage = uibutton(app.UIFigure, 'push');
            app.UploadImage.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImage.Text = 'Upload Image';
            app.RMSContrastButton = uibutton(app.UIFigure, 'push');
            app.RMSContrastButton.ButtonPushedFcn = createCallbackFcn(app, @RMSContrastButtonPushed, true);
            app.RMSContrastButton.Text = 'RMS Contrast';
            app.GrayscaleButton = uibutton(app.UIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale ';
            app.DiseaseDetectionButton = uibutton(app.UIFigure, 'push');
            app.DiseaseDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DiseaseDetectionButtonPushed, true);
            app.DiseaseDetectionButton.Text = 'Disease Detection';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.ResultsLogTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsLogTextAreaLabel.Text = 'Results Log  ';
            app.ResultsLogTextArea = uitextarea(app.UIFigure);
            app.ResultsLogTextArea.Editable = 'off';
            app.WeightTable = uitable(app.UIFigure);
            app.WeightTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.WeightTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newcode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end